classdef FKin_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CreateRobotButton             matlab.ui.control.Button
        EndEffectorPositionEditField  matlab.ui.control.NumericEditField
        EndEffectorPositionEditFieldLabel  matlab.ui.control.Label
        Joint2Slider                  matlab.ui.control.Slider
        Joint2SliderLabel             matlab.ui.control.Label
        Joint1Slider                  matlab.ui.control.Slider
        Joint1SliderLabel             matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        robot = DrawBot; % This is a Serial Link Created robot 
        jointangles= [ 0 0]; % these are the joint angles of the robot
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Joint1Slider
        function Joint1SliderChanged(app, event)
            theta1 = app.Joint1Slider.Value;
            %calculate the forward kinematics
            T=app.robot.fkine(theta1); %for 1dof (need to change)
            robotPosition = T.t; %get the position data
            app.EndEffectorPosChanged.Value = robotPosition(1); %update the UI
        end

        % Value changed function: Joint2Slider
        function Joint2SliderChanged(app, event)
            theta2 = app.Joint1Slider.Value;
            %calculate the forward kinematics
            T=app.robot.fkine(theta2); %for 1dof (need to change)
            robotPosition = T.t; %get the position data
            app.EndEffectorPosChanged.Value = robotPosition(2);
            
        end

        % Value changed function: EndEffectorPositionEditField
        function EndEffectorPosChanged(app, event)
            Value = app.EndEffectorPositionEditField.Value;
        end

        % Button pushed function: CreateRobotButton
        function Create_Callback(app, event)
            app.robot.plot(app.jointangles)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'UIFigure';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderChanged, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderChanged, true);
            app.EndEffectorPositionEditFieldLabel = uilabel(app.UIFigure);
            app.EndEffectorPositionEditFieldLabel.Text = 'End Effector Position';
            app.EndEffectorPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndEffectorPositionEditField.ValueChangedFcn = createCallbackFcn(app, @EndEffectorPosChanged, true);
            app.CreateRobotButton = uibutton(app.UIFigure, 'push');
            app.CreateRobotButton.ButtonPushedFcn = createCallbackFcn(app, @Create_Callback, true);
            app.CreateRobotButton.Text = 'Create Robot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FKin_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end